classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        browseButton       matlab.ui.control.Button
        ImageN             matlab.ui.control.Image
        ImageP             matlab.ui.control.Image
        ImageD             matlab.ui.control.Image
        ImageT             matlab.ui.control.Image
        NormalLabel        matlab.ui.control.Label
        ProtanopiaLabel    matlab.ui.control.Label
        DeuteranopiaLabel  matlab.ui.control.Label
        TritanopiaLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        fileName % file name
        filePath % file path
        fileExtension % file extension
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
        [a,b] = uigetfile('*.*');
        imageName = strcat(b,a);
        [app.filePath, app.fileName, app.fileExtension] = fileparts(imageName);
%         close(app.UIFigure); 
        Daltonization.daltonize(imageName, 0, 0);
        app.ImageN.ImageSource = imread(imageName);
        app.ImageP.ImageSource = imread(append(app.filePath, '\', app.fileName, '_p', app.fileExtension));
        app.ImageD.ImageSource = imread(append(app.filePath, '\', app.fileName, '_d', app.fileExtension));
        app.ImageT.ImageSource = imread(append(app.filePath, '\', app.fileName, '_t', app.fileExtension));
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.browseButton = uibutton(app.UIFigure, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.ImageN = uiimage(app.UIFigure);
            app.ImageP = uiimage(app.UIFigure);
            app.ImageD = uiimage(app.UIFigure);
            app.ImageT = uiimage(app.UIFigure);
            app.NormalLabel = uilabel(app.UIFigure);
            app.NormalLabel.Text = 'Normal';
            app.ProtanopiaLabel = uilabel(app.UIFigure);
            app.ProtanopiaLabel.Text = 'Protanopia';
            app.DeuteranopiaLabel = uilabel(app.UIFigure);
            app.DeuteranopiaLabel.Text = 'Deuteranopia';
            app.TritanopiaLabel = uilabel(app.UIFigure);
            app.TritanopiaLabel.Text = 'Tritanopia';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end