classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TriaMonocromacyUIFigure  matlab.ui.Figure
        ImageD                   matlab.ui.control.Image
        ImageT                   matlab.ui.control.Image
        ImageP                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Image3_3                 matlab.ui.control.Image
        Image3_2                 matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Button                   matlab.ui.control.Button
        ImageN                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        fileName % file name
        filePath % file path
        fileExtension % file extension
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
        [a,b] = uigetfile('*.*');
        imageName = strcat(b,a);
        [app.filePath, app.fileName, app.fileExtension] = fileparts(imageName);
%         close(app.TriaMonocromacyUIFigure); 
        Daltonization.daltonize(imageName, 0, 0);
        app.ImageN.ImageSource = imread(imageName);
        app.ImageP.ImageSource = imread(append(app.filePath, '/', app.fileName, '_p', app.fileExtension));
        app.ImageD.ImageSource = imread(append(app.filePath, '/', app.fileName, '_d', app.fileExtension));
        app.ImageT.ImageSource = imread(append(app.filePath, '/', app.fileName, '_t', app.fileExtension));
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TriaMonocromacyUIFigure = uifigure('Visible', 'off');
            app.TriaMonocromacyUIFigure.Name = 'Tria Monocromacy';
            app.Image = uiimage(app.TriaMonocromacyUIFigure);
            app.Image.ImageSource = 'background.png';
            app.ImageN = uiimage(app.TriaMonocromacyUIFigure);
            app.ImageN.ImageSource = 'imbg.png';
            app.Button = uibutton(app.TriaMonocromacyUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'button.png';
            app.Button.Text = '';
            app.Image2 = uiimage(app.TriaMonocromacyUIFigure);
            app.Image2.ImageSource = 'mark.png';
            app.Image3_2 = uiimage(app.TriaMonocromacyUIFigure);
            app.Image3_2.ImageSource = 'imbg.png';
            app.Image3_3 = uiimage(app.TriaMonocromacyUIFigure);
            app.Image3_3.ImageSource = 'imbg.png';
            app.Image3 = uiimage(app.TriaMonocromacyUIFigure);
            app.Image3.ImageSource = 'imbg.png';
            app.ImageP = uiimage(app.TriaMonocromacyUIFigure);
            app.ImageP.ImageSource = 'fec.png';
            app.ImageT = uiimage(app.TriaMonocromacyUIFigure);
            app.ImageT.ImageSource = 'fec.png';
            app.ImageD = uiimage(app.TriaMonocromacyUIFigure);
            app.ImageD.ImageSource = 'fec.png';
            % Show the figure after all components are created
            app.TriaMonocromacyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TriaMonocromacyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TriaMonocromacyUIFigure)
        end
    end
end